classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        PDControllerGainsPanel          matlab.ui.container.Panel
        RollControlLabel                matlab.ui.control.Label
        PitchControlLabel               matlab.ui.control.Label
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditField       matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel      matlab.ui.control.Label
        TimeConstantEditField           matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditField         matlab.ui.control.NumericEditField
        AltitudeControlLabel_2          matlab.ui.control.Label
        TimeConstantEditField_2Label    matlab.ui.control.Label
        TimeConstantEditField_2         matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditField_2       matlab.ui.control.NumericEditField
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditField_2     matlab.ui.control.NumericEditField
        TimeConstantEditField_3Label    matlab.ui.control.Label
        TimeConstantEditField_3         matlab.ui.control.NumericEditField
        DerivativeGainEditField_3Label  matlab.ui.control.Label
        DerivativeGainEditField_3       matlab.ui.control.NumericEditField
        ProportionalGainEditField_3Label  matlab.ui.control.Label
        ProportionalGainEditField_3     matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageEditFieldLabel   matlab.ui.control.Label
        MaxMotorVoltageEditField        matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositionEditFieldLabel       matlab.ui.control.Label
        CoMPositionEditField            matlab.ui.control.NumericEditField
        CoMPositionEditField_2          matlab.ui.control.NumericEditField
        CoMPositionEditField_3          matlab.ui.control.NumericEditField
        CamerasTab                      matlab.ui.container.Tab
        CameraSelectionButtonGroup      matlab.ui.container.ButtonGroup
        BottomCameraAButton             matlab.ui.control.RadioButton
        BottomCameraBButton             matlab.ui.control.RadioButton
        CameraControlPanel              matlab.ui.container.Panel
        SideCameraLabel_2               matlab.ui.control.Label
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditField      matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditField_2    matlab.ui.control.NumericEditField
        HoldReleaseButton               matlab.ui.control.Button
        HoldReleaseButton_2             matlab.ui.control.Button
        ROIHoldPositionpxEditField_3Label  matlab.ui.control.Label
        ROIHoldPositionpxEditField_3    matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditField_4    matlab.ui.control.NumericEditField
        BottomCameraALabel              matlab.ui.control.Label
        BottomCameraBLabel              matlab.ui.control.Label
        ROIHoldPositionpxEditField_5Label  matlab.ui.control.Label
        ROIHoldPositionpxEditField_5    matlab.ui.control.NumericEditField
        HoldReleaseButton_3             matlab.ui.control.Button
        ROIHoldPositionpxEditField_7Label  matlab.ui.control.Label
        ROIHoldPositionpxEditField_7    matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditField_8    matlab.ui.control.NumericEditField
        HoldReleaseButton_4             matlab.ui.control.Button
        BottomCameraBLabel_2            matlab.ui.control.Label
        ROIHoldPositionpxEditField_10   matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditField_9    matlab.ui.control.NumericEditField
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel                 matlab.ui.control.Label
        PositionEditField_16Label       matlab.ui.control.Label
        PositionEditField_16            matlab.ui.control.NumericEditField
        PositionEditField_17            matlab.ui.control.NumericEditField
        PositionEditField_18            matlab.ui.control.NumericEditField
        PositionEditField_19            matlab.ui.control.NumericEditField
        PositionEditField_20            matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Value changed function: ProportionalGainEditField
        function ProportionalGainEditFieldValueChanged(app, event)
            value = app.ProportionalGainEditField.Value;
            assignin('base','params.rollCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditField
        function DerivativeGainEditFieldValueChanged(app, event)
            value = app.DerivativeGainEditField.Value;
            assignin('base','params.rollCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditField
        function TimeConstantEditFieldValueChanged(app, event)
            value = app.TimeConstantEditField.Value;
            assignin('base','params.rollCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditField_2
        function ProportionalGainEditField_2ValueChanged(app, event)
            value = app.ProportionalGainEditField_2.Value;
            assignin('base','params.pitchCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditField_2
        function DerivativeGainEditField_2ValueChanged(app, event)
            value = app.DerivativeGainEditField_2.Value;
            assignin('base','params.pitchCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditField_2
        function TimeConstantEditField_2ValueChanged(app, event)
            value = app.TimeConstantEditField_2.Value;
            assignin('base','params.pitchCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditField_3
        function ProportionalGainEditField_3ValueChanged(app, event)
            value = app.ProportionalGainEditField_3.Value;
            assignin('base','params.altCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditField_3
        function DerivativeGainEditField_3ValueChanged(app, event)
            value = app.DerivativeGainEditField_3.Value;
            assignin('base','params.altCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditField_3
        function TimeConstantEditField_3ValueChanged(app, event)
            value = app.TimeConstantEditField_3.Value;
            assignin('base','params.altCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            assignin('base','params.mtr1ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            assignin('base','params.mtr2ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            assignin('base','params.mtr3ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MaxMotorVoltageEditField
        function MaxMotorVoltageEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageEditField.Value;
            assignin('base','params.maxOutVltg_vlt',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditField
        function CoMPositionEditFieldValueChanged(app, event)
            value = app.CoMPositionEditField.Value;
            assignin('base','params.initCoMPosVec_cm',value);
            set_param(gcs,'SimulationCommand','Update');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.OperationModeButtonGroup = uibuttongroup(app.ControlTab);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.ManualButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.PDControllerGainsPanel = uipanel(app.ControlTab);
            app.PDControllerGainsPanel.Title = 'PD Controller Gains';
            app.RollControlLabel = uilabel(app.PDControllerGainsPanel);
            app.RollControlLabel.Text = 'Roll Control';
            app.PitchControlLabel = uilabel(app.PDControllerGainsPanel);
            app.PitchControlLabel.Text = 'Pitch Control';
            app.ProportionalGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditField = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldValueChanged, true);
            app.TimeConstantEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditField = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditField.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditField = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditField.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldValueChanged, true);
            app.AltitudeControlLabel_2 = uilabel(app.PDControllerGainsPanel);
            app.AltitudeControlLabel_2.Text = 'Altitude Control';
            app.TimeConstantEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_2Label.Text = 'Time Constant';
            app.TimeConstantEditField_2 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditField_2ValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditField_2 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditField_2.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditField_2ValueChanged, true);
            app.ProportionalGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditField_2 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditField_2.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditField_2ValueChanged, true);
            app.TimeConstantEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_3Label.Text = 'Time Constant';
            app.TimeConstantEditField_3 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditField_3.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditField_3ValueChanged, true);
            app.DerivativeGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_3Label.Text = 'Derivative Gain';
            app.DerivativeGainEditField_3 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditField_3.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditField_3ValueChanged, true);
            app.ProportionalGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_3Label.Text = 'Proportional Gain';
            app.ProportionalGainEditField_3 = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditField_3.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditField_3ValueChanged, true);
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageEditFieldLabel.Text = 'Max Motor Voltage';
            app.MaxMotorVoltageEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositionEditFieldLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositionEditFieldLabel.Text = 'CoM Position';
            app.CoMPositionEditField = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditField.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldValueChanged, true);
            app.CoMPositionEditField_2 = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditField_3 = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.CameraSelectionButtonGroup = uibuttongroup(app.CamerasTab);
            app.CameraSelectionButtonGroup.Title = 'Camera Selection';
            app.BottomCameraAButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraAButton.Text = 'Bottom Camera A';
            app.BottomCameraAButton.Value = true;
            app.BottomCameraBButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraBButton.Text = 'Bottom Camera B';
            app.CameraControlPanel = uipanel(app.CamerasTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.SideCameraLabel_2 = uilabel(app.CameraControlPanel);
            app.SideCameraLabel_2.Text = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.CameraControlPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditField = uieditfield(app.CameraControlPanel, 'numeric');
            app.ROIHoldPositionpxEditField_2 = uieditfield(app.CameraControlPanel, 'numeric');
            app.HoldReleaseButton = uibutton(app.CameraControlPanel, 'push');
            app.HoldReleaseButton.Text = 'Hold/Release';
            app.HoldReleaseButton_2 = uibutton(app.CameraControlPanel, 'push');
            app.HoldReleaseButton_2.Text = 'Hold/Release';
            app.ROIHoldPositionpxEditField_3Label = uilabel(app.CameraControlPanel);
            app.ROIHoldPositionpxEditField_3Label.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditField_3 = uieditfield(app.CameraControlPanel, 'numeric');
            app.ROIHoldPositionpxEditField_4 = uieditfield(app.CameraControlPanel, 'numeric');
            app.BottomCameraALabel = uilabel(app.CameraControlPanel);
            app.BottomCameraALabel.Text = 'Bottom Camera A';
            app.BottomCameraBLabel = uilabel(app.CameraControlPanel);
            app.BottomCameraBLabel.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditField_5Label = uilabel(app.CameraControlPanel);
            app.ROIHoldPositionpxEditField_5Label.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditField_5 = uieditfield(app.CameraControlPanel, 'numeric');
            app.HoldReleaseButton_3 = uibutton(app.CameraControlPanel, 'push');
            app.HoldReleaseButton_3.Text = 'Hold/Release';
            app.ROIHoldPositionpxEditField_7Label = uilabel(app.CameraControlPanel);
            app.ROIHoldPositionpxEditField_7Label.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditField_7 = uieditfield(app.CameraControlPanel, 'numeric');
            app.ROIHoldPositionpxEditField_8 = uieditfield(app.CameraControlPanel, 'numeric');
            app.HoldReleaseButton_4 = uibutton(app.CameraControlPanel, 'push');
            app.HoldReleaseButton_4.Text = 'Hold/Release';
            app.BottomCameraBLabel_2 = uilabel(app.CameraControlPanel);
            app.BottomCameraBLabel_2.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditField_9 = uieditfield(app.CameraControlPanel, 'numeric');
            app.ROIHoldPositionpxEditField_10 = uieditfield(app.CameraControlPanel, 'numeric');
            app.CameraOrientationPanel = uipanel(app.CamerasTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel.Text = 'Side Camera';
            app.EulerAngleEditFieldLabel = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditFieldLabel.Text = 'Euler Angle';
            app.EulerAngleEditField = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngleEditField_2Label = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_2Label.Text = 'Euler Angle';
            app.EulerAngleEditField_2 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_6 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_7 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_8Label = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_8Label.Text = 'Position';
            app.PositionEditField_8 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_9 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_10 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.SlantCameraLabel = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel.Text = 'Slant Camera';
            app.EulerAngleEditField_3Label = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_3Label.Text = 'Euler Angle';
            app.EulerAngleEditField_3 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_11Label = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_11Label.Text = 'Position';
            app.PositionEditField_11 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_12 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_13 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_14 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_15 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_16Label = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_16Label.Text = 'Position';
            app.PositionEditField_16 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_17 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_18 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_19 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_20 = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalEditFieldLabel.Text = 'Sync Interval';
            app.SyncIntervalEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.IPAddressEditFieldLabel = uilabel(app.Machine2Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.PortNumberEditFieldLabel = uilabel(app.Machine2Panel);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.MinDelayEditFieldLabel = uilabel(app.Machine2Panel);
            app.MinDelayEditFieldLabel.Text = 'Min Delay';
            app.MinDelayEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.Machine3Panel = uipanel(app.CommunicationTab);
            app.Machine3Panel.Title = 'Machine 3';
            app.IPAddressEditField_2Label = uilabel(app.Machine3Panel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditField_2 = uieditfield(app.Machine3Panel, 'numeric');
            app.PortNumberEditField_2Label = uilabel(app.Machine3Panel);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditField_2 = uieditfield(app.Machine3Panel, 'numeric');
            app.MinDelayEditField_2Label = uilabel(app.Machine3Panel);
            app.MinDelayEditField_2Label.Text = 'Min Delay';
            app.MinDelayEditField_2 = uieditfield(app.Machine3Panel, 'numeric');
            app.Machine4Panel = uipanel(app.CommunicationTab);
            app.Machine4Panel.Title = 'Machine 4';
            app.IPAddressEditField_3Label = uilabel(app.Machine4Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditField_3 = uieditfield(app.Machine4Panel, 'numeric');
            app.PortNumberEditField_3Label = uilabel(app.Machine4Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditField_3 = uieditfield(app.Machine4Panel, 'numeric');
            app.MinDelayEditField_3Label = uilabel(app.Machine4Panel);
            app.MinDelayEditField_3Label.Text = 'Min Delay';
            app.MinDelayEditField_3 = uieditfield(app.Machine4Panel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end